classdef SolarSpecGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectrabuilderUIFigure          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        RightPanel                      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        KineticLevelDisplay             matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        BackgroundLvlDisplay            matlab.ui.control.Label
        LogarithmicdatawillbeexportedtoCSVLabel  matlab.ui.control.Label
        ExportSpectraDataButton         matlab.ui.control.Button
        CentertimesforspectraSeperatewithcolonLabel  matlab.ui.control.Label
        SpectraTimeWidthLabel           matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout6                     matlab.ui.container.GridLayout
        NextButton                      matlab.ui.control.Button
        PrevButton                      matlab.ui.control.Button
        KeepSpecPlotYaxisfixedCheckBox  matlab.ui.control.CheckBox
        GridLayout5                     matlab.ui.container.GridLayout
        ButtonGroup                     matlab.ui.container.ButtonGroup
        LinearYscaleButton              matlab.ui.control.RadioButton
        LogYScaleButton                 matlab.ui.control.RadioButton
        KeepKinPlotaxisfixedCheckBox    matlab.ui.control.CheckBox
        KinPlot                         matlab.ui.control.UIAxes
        SpecPlot                        matlab.ui.control.UIAxes
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ExportLogDataCSVButton          matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ExportFiguresButton             matlab.ui.control.Button
        LogLogFigureButton              matlab.ui.control.Button
        SaveVarstoWorkspaceButton       matlab.ui.control.Button
        LoadLogData                     matlab.ui.control.Button
        LoadCreateError                 matlab.ui.control.Label
        GridLayout9                     matlab.ui.container.GridLayout
        LogDataButton                   matlab.ui.control.Button
        LinearDataButton                matlab.ui.control.Button
        LoadTDMSdataButton              matlab.ui.control.Button
        SpectraTimeWidthSlider          matlab.ui.control.Slider
    end


    properties (Access = private)
        LogApp % Store the second application in this object
        waves % Store list of wavelengths from header names
        BackgroundLevel % Store BackgroundLvl table
        LinDataSort % Store the sorted LinData table
        LogDataSort % Store the sorted LogData table
        dataRG % Store the DataRG table
        timeRG % Store the TimeRG table
        KinData % Store the loaded log data for only once
        changeAxes % Store boolean changeAxes if the axes has to change
        FixedYRange % Store the range of Y to keep axes fixed
        SpecYRange % Store the range of Y of the spectra axis
        SpecDataPlot % Store the spec plot data of the specified index
        KinDataPlot % Store the kin plot data of the specified index
        DataPlot % Hold the kin plot object
        ShadeArea % Array to store area limits
        Traces % Store the number of traces
        XPoints % Array to store x location of ROI points
        XPointsROI % Store points within one function among multiple if else
        KinShade % Store shade among one functinon
        rst % To determine whether or not to reload data folder
        iter % Iterator for load button use
        X1 % Left most roi point for each area
        X2 % Right most roi point for each area
        TimeWidths % Store time width table
        TimeFields % Store time centres in table rather than UIObject
    end

    properties (Access = public)
        Path % Store the path of the data folder
        TamOn % Store whether TAM data is being used or not
        positions % Store list of positions from header names

        % These properties are essentially the input arguments for LogLog app
        LinearData % Store the LinData table
        LogarithmData % Store the LogData table
        time % Store the original X data
        LogTimeArr % Store the Log of the X data in an array
        KinInd % Store the index of which data file we are looking at
        Plot_Wavelengths % Sorted string2double wavelengths nums
        Table % Store the temporary LogTable T to use in functions
        IsLogDataOnly % Store if LogData is loaded and not calculated
    end

    methods (Access = private)

        function RGPlot(app)
            % Sort the wavelgenths headers
            app.Plot_Wavelengths = sort(str2double(app.waves));

            % Clear plot to ensure no overlap
            cla(app.SpecPlot, 'reset');

            % Set number of colors for bars
            RGcolor = hsv(10);
            RGcolor(:,3) = 0.5;

            default(app);

            % Keep X scale Logarithmic
            app.KinPlot.XScale = 'log';

            % Plot reference Y = 0 line if Y scale is linear.
            if strcmp(app.KinPlot.YScale,'linear') == 1
                KinRef = refline(app.KinPlot, 0, 0);
                KinRef.Color = [0 0 0];
            end

            % Plot Spectral data and check if there is more than one
            % tdms file opened. Change plot accordingly
            [m,~] = size(app.SpecDataPlot);
            if m > 1
                for i = 1:1:size(app.SpecDataPlot,2)
                    plot(app.SpecPlot, app.Plot_Wavelengths, app.SpecDataPlot(:,i), 'Color', RGcolor(i,:));
                    hold(app.SpecPlot, 'on');
                end
                SpecRef = refline(app.SpecPlot,0,0);
                SpecRef.Color = [0 0 0];
                hold(app.SpecPlot, 'off');

                if app.changeAxes
                    if app.TamOn == 0
                        axis(app.SpecPlot, [app.Plot_Wavelengths(1) max(app.Plot_Wavelengths) min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))]);
                        app.SpecYRange = [min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))];
                    else
                        app.SpecYRange = [min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))];
                    end
                else
                    if app.TamOn == 0
                        axis(app.SpecPlot, [app.Plot_Wavelengths(1) max(app.Plot_Wavelengths) app.SpecYRange]);
                    end
                end
            else
                for i = 1:1:size(app.SpecDataPlot,2)
                    plot(app.SpecPlot, app.Plot_Wavelengths, app.SpecDataPlot(:,i), 'Color', RGcolor(i,:));
                    hold(app.SpecPlot, 'on');
                end
                SpecRef = refline(app.SpecPlot,0,0);
                SpecRef.Color = [0 0 0];
                hold(app.SpecPlot, 'off');

                if app.changeAxes
                    if app.TamOn == 0
                        if app.Plot_Wavelengths(1) == max(app.Plot_Wavelengths)
                            axis(app.SpecPlot, [app.Plot_Wavelengths(1) app.Plot_Wavelengths(1)+1 min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))]);
                            app.SpecYRange = [min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))];
                        else
                            axis(app.SpecPlot, [app.Plot_Wavelengths(1) max(app.Plot_Wavelengths) min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))]);
                            app.SpecYRange = [min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))];
                        end
                    else
                        app.SpecYRange = [min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))];
                    end
                else
                    if app.TamOn == 0
                        if app.Plot_Wavelengths(1) == max(app.Plot_Wavelengths)
                            axis(app.SpecPlot, [app.Plot_Wavelengths(1) max(app.Plot_Wavelengths) app.SpecYRange]);
                        else
                            axis(app.SpecPlot, [app.Plot_Wavelengths(1) app.Plot_Wavelengths(1)+1 app.SpecYRange]);
                        end
                    end
                end
            end

            % Display the proper wavelengths/position values depending
            % on TAS of TAM data
            if app.TamOn == 0
                app.KineticLevelDisplay.Text = "Displaying kinetic at " + app.Plot_Wavelengths(app.KinInd) + " nm";
                app.BackgroundLvlDisplay.Text = "Background level was " + table2array(app.BackgroundLevel(1,app.KinInd)) + " V"; %RG
            else
                app.KineticLevelDisplay.Text = "Disp: " + app.positions(app.KinInd);
                app.BackgroundLvlDisplay.Text = "Background level was " + table2array(app.BackgroundLevel(1,app.KinInd)) + " V";
            end
            num1 = num2cell(app.TimeFields)
            num2 = app.TimeWidths
%             T = cellfun(@(M) mat2cell(M, ones(1, size(M,1)), size(M,2)), ThatCell, 'Uniform', 0);
%             data = [T{1}, T{2}];
            app.UITable.Data = [num2cell(app.TimeFields); app.TimeWidths];

        end

        function RGBuildSpecData(app, ~, ~)
            RGcolor = hsv(10);
            RGcolor(:,3) = 0.5;
            %% If called without callback execute default
            if nargin == 1

                % Populate correct properties for spec data plot
                app.timeRG = app.dataRG(:,1);
                TimeRGSpec = app.LinDataSort(:,1);
                app.Traces = size(app.LinDataSort, 2) - 1;

                % Create vars to use for proper use of slider object/input
                SpecCenterTimes = app.TimeFields;
                SpecWidth = app.SpectraTimeWidthSlider.Value/100;

                app.SpecDataPlot = [];

                if isempty(get(app.KinPlot, 'Children')) == 1
                    %% On first draw, set Graph
                    app.XPoints = [];
                    app.ShadeArea = [];

                    for Spec = 1:1:size(SpecCenterTimes)
                        CenterTime = SpecCenterTimes(Spec);
                        StartTime = CenterTime *(1 - SpecWidth/2);
                        EndTime = CenterTime * (1 + SpecWidth/2);

                        StartInd = find(StartTime > table2array(TimeRGSpec),1,'last') + 1;
                        EndInd = find(EndTime < table2array(TimeRGSpec),1,'first') - 1;

                        app.SpecDataPlot(:,Spec) = mean(table2array(app.LinDataSort(StartInd:EndInd,2:end)),1);
                        app.ShadeArea{Spec,1} = [StartTime EndTime];
                    end
                    app.TimeWidths = app.ShadeArea;
                    % Cell array to keep track of every ROI position
                    app.XPointsROI = [];

                    % Cell array to keep track of shade area
                    app.KinShade = [];

                    %% Start of Plotting interactible area
                    grid(app.KinPlot, 'off')

                    %% Plot bars and store the data of the ends
                    for i = 1:1:size(app.SpecDataPlot,2)
                        hold(app.KinPlot, 'on');
                        RGarea = app.ShadeArea{i,1};
                        app.KinShade{i} = area(app.KinPlot, RGarea, [1 1]);
                        hold(app.KinPlot, 'off');

                        %% Store Upper and Lower X coordinates
                        app.XPoints(i,:) = app.KinShade{i}.XData;
                        app.KinShade{i}.FaceColor = RGcolor(i,:);

                        if strcmp(app.KinPlot.YScale, 'log')
                            app.KinShade{i}.BaseValue = 1e-100;
                        else
                            app.KinShade{i}.BaseValue = -1;
                        end
                    end

                    for i = 1:1:size(app.XPoints)
                        if ~isnan(app.XPoints(i,1)) && ~isnan(app.XPoints(i,2))

                            % Implement drawpoint for either side of the area
                            app.X1 = drawpoint(app.KinPlot, "Position", [app.XPoints(i,1) 0], 'DrawingArea', [0 0 app.KinPlot.XLim(2)-app.KinPlot.XLim(1) 0]);
                            app.X2 = drawpoint(app.KinPlot, "Position", [app.XPoints(i,2) 0], 'DrawingArea', [0 0 app.KinPlot.XLim(2)-app.KinPlot.XLim(1) 0]);

                            %% Add callbacks for when point if moved
                            addlistener(app.X1, "MovingROI", @(src,event)RGBuildSpecData(app,src,event));
                            addlistener(app.X2, "MovingROI", @(src,event)RGBuildSpecData(app,src,event));

                            %% Store points in vector
                            app.XPointsROI{i,1} = app.X1;
                            app.XPointsROI{i,2} = app.X2;
                        end
                    end
                    
                else
                    
                    TestCenter = []; %#ok<NASGU>
                    TestCenter = app.XPoints(:,1) + app.XPoints(:,2);
                    TestCenter = TestCenter./2;

                    TimeRGSpec = app.LinDataSort(:,1);
                    TimeFieldsNew = "";

                    for i = 1:1:size(TestCenter)
                        TimeFieldsNew = TimeFieldsNew + num2str(TestCenter(i), '%.2E') + "; ";
                        StartTime = app.XPoints(i,1);
                        EndTime = app.XPoints(i,2);

                        StartInd = find(StartTime > table2array(TimeRGSpec),1,'last') + 1;
                        EndInd = find(EndTime < table2array(TimeRGSpec),1,'first') - 1;

                        app.SpecDataPlot(:,i) = mean(table2array(app.LinDataSort(StartInd:EndInd,2:end)),1);
                        app.ShadeArea{i,1} = [StartTime EndTime];
                    end
                    app.TimeFields=TimeFieldsNew;
                    app.TimeWidths = app.ShadeArea;

                    for i = 1:1:size(app.SpecDataPlot,2)
                        hold(app.KinPlot, 'on')
                        app.KinShade{i}.XData = app.ShadeArea{i,1};
                        app.XPoints(i,:) = app.KinShade{i}.XData;
                        app.KinShade{i}.FaceColor = RGcolor(i,:);
                        hold(app.KinPlot, 'off')
                    end

                end
                RGPlot(app);
                
            else
                
                % Use to handle shader by mouse input change and when
                % switching from log back to lin to properly overwrite
                % shaded areas
                
                TestCenter = []; %#ok<NASGU>
                TestCenter = app.XPoints(:,1) + app.XPoints(:,2);
                TestCenter = TestCenter./2;

                TimeRGSpec = app.LinDataSort(:,1);
                TimeFieldsNew = "";

                for i = 1:1:size(TestCenter)
                    TimeFieldsNew = TimeFieldsNew + num2str(TestCenter(i), '%.2E') + "; ";
                    StartTime = app.XPoints(i,1);
                    EndTime = app.XPoints(i,2);

                    StartInd = find(StartTime > table2array(TimeRGSpec),1,'last') + 1;
                    EndInd = find(EndTime < table2array(TimeRGSpec),1,'first') - 1;

                    app.SpecDataPlot(:,i) = mean(table2array(app.LinDataSort(StartInd:EndInd,2:end)),1);
                    app.ShadeArea{i,1} = [StartTime EndTime];
                end
                

                app.TimeFields=TimeFieldsNew;
                app.TimeWidths = app.ShadeArea;
                
                %if odd or even index of shade area, either add or subtract
                %new position width
                
                %instead of looping through all shade areas each time, use
                %the event value to check which ROI is being used and
                %update accordingly? MAYBE BY CASE ID?

                    for i = 1:1:size(app.XPointsROI)
                        app.ShadeArea{i,1}=[app.XPointsROI{i,1}.Position(1),app.XPointsROI{i,2}.Position(1)];
                    end
                    
                    for i = 1:1:size(app.SpecDataPlot,2)

%                     if mod(i,2) == 1 % If odd (starting at 1)
                        hold(app.KinPlot, 'on')
                        app.KinShade{i}.XData = app.ShadeArea{i,1};
                        app.XPoints(i,:) = app.KinShade{i}.XData;
%                         w = app.TimeFields(i) - app.KinShade{i}.XData;
%                         app.ShadeArea{i+1,1} = app.KinShade{i+1}.XData + w;
%                         app.KinShade{i+1} = app.KinShade{i+1}.XData + w;
                        app.KinShade{i}.FaceColor = RGcolor(i,:);
                        hold(app.KinPlot, 'off')

                        %% Store Upper and Lower X coordinates
                        app.XPoints(i,:) = app.KinShade{i}.XData;
                        app.KinShade{i}.FaceColor = RGcolor(i,:);

                        if strcmp(app.KinPlot.YScale, 'log')
                            app.KinShade{i}.BaseValue = 1e-100;
                        else
                            app.KinShade{i}.BaseValue = -1;
                        end
                    end
%                     else % If even
%                         hold(app.KinPlot, 'on')
%                         app.KinShade{i}.XData = app.ShadeArea{i,1};
%                         app.XPoints(i,:) = app.KinShade{i}.XData;
% %                         w = app.KinShade{i}.XData - app.TimeFields(i);
% % %                         app.XPoints(i-1,:) = app.KinShade{i-1}.XData - w;
% %                         app.ShadeArea{i-1,1} = app.KinShade{i-1}.XData - w;
% %
%                         app.KinShade{i}.FaceColor = RGcolor(i,:);
%                         hold(app.KinPlot, 'off')
%
%                         %% Store Upper and Lower X coordinates
%                         app.XPoints(i,:) = app.KinShade{i}.XData;
%                         app.KinShade{i}.FaceColor = RGcolor(i,:);
%
%                         if strcmp(app.KinPlot.YScale, 'log')
%                             app.KinShade{i}.BaseValue = 1e-100;
%                         else
%                             app.KinShade{i}.BaseValue = -1;
%                         end
%                     end
                    
%                 for i = 1:1:size(app.SpecDataPlot,2)
%                     hold(app.KinPlot, 'on');
%                     RGarea = app.ShadeArea{i,1};
%                     delete(app.KinShade{i});
%                     app.KinShade{i} = area(app.KinPlot, RGarea, [1 1]);
%                     app.XPoints(i,:) = app.KinShade{i}.XData;
%                     app.KinShade{i}.FaceColor = RGcolor(i,:);
%                     if i==1
%                         if strcmp(app.KinPlot.YScale, 'log')
%                             app.KinShade{i}.BaseValue = 1e-100;
%                         else
%                             app.KinShade{i}.BaseValue = -1;
%                         end
%                     end
%                 end
                
                RGPlot(app);
            end
        end


        function plotLogData(app)
            % Populate variables with loaded properties
            app.KinDataPlot = app.KinData(:,app.KinInd);
            app.Traces = size(app.Table, 2) - 1;

            % LogPlot isn't a property so it must be checked for existence
            if exist('LogPlot', 'var') == 1
                delete(LogPlot); %#ok<NODEF>
            end

            % Plot loaded log data
            LogPlot = plot(app.KinPlot, table2array(app.timeRG), table2array(app.KinDataPlot), 'b'); %#ok<NASGU>

            % Check if Y-axis is to be kept constant
            if app.KeepKinPlotaxisfixedCheckBox.Value == 0
                axis(app.KinPlot,[1E-7 max(table2array(app.timeRG)) min(table2array(app.KinDataPlot)) max(table2array(app.KinDataPlot))]);
            else
                axis(app.KinPlot,[1E-7 max(table2array(app.timeRG)) app.FixedYRange]);
            end

            % Keep X scale Logarithmic
            app.KinPlot.XScale = 'log';

            % Check if lin or log scale
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.LogYScaleButton
                app.KinPlot.YScale = 'log';
            else
                app.KinPlot.YScale = 'linear';
                KinRef = refline(app.KinPlot, 0, 0);
                KinRef.Color = [0 0 0];
            end

            % Display proper wavelength on app
            if app.TamOn == 0
                app.KineticLevelDisplay.Text = "Displaying kinetic at " + app.Plot_Wavelengths(app.KinInd) + " nm";
            else
                app.KineticLevelDisplay.Text = "Disp: " + app.positions(app.KinInd);
            end
        end

        function clearProperties(app)
            % Set all properties back to default values
            app.TamOn = 0;
            app.waves = {};
            app.positions = {};
            app.BackgroundLevel = {};
            app.LinDataSort = {};
            app.LogDataSort = {};
            app.dataRG = {};
            app.timeRG = {};
            app.IsLogDataOnly = 0;
            app.changeAxes = 0;
            app.FixedYRange = [];
            app.SpecYRange = [];
            app.KinDataPlot = {};
            app.SpecDataPlot = {};
            delete(app.DataPlot);
            app.ShadeArea = [];
            app.Traces = [];
            app.Table = {};
            app.XPoints = [];
            app.XPointsROI = [];
            app.KinShade = [];
            app.Path = "";
            app.LinearData = {};
            app.LogarithmData = {};
            app.time = [];
            app.LogTimeArr = [];
            app.KinInd = 1;
            app.Plot_Wavelengths = {};

            app.LinearYscaleButton.Value = true;
            app.KeepKinPlotaxisfixedCheckBox.Value = 0;
            app.KeepSpecPlotYaxisfixedCheckBox.Value = 0;
            app.TimeFields = '1E-6; 1E-5; 1E-4; 1E-3; 1E-2; 1E-1';
            app.SpectraTimeWidthSlider.Value = 10;

            cla(app.KinPlot, "reset")
            cla(app.SpecPlot, "reset")
        end

        function default(app)
            % Remove time from data to use as separate var
            Kindata = app.dataRG(:,2:end);
            app.KinDataPlot = Kindata(:,app.KinInd);

            % Plot the data on axes figure
            delete(app.DataPlot)
            hold(app.KinPlot, 'on');
            app.DataPlot = plot(app.KinPlot, table2array(app.timeRG), table2array(app.KinDataPlot), 'b');
            hold(app.KinPlot, 'off');

            % Check if Y-axis is to be kept constant
            if app.KeepKinPlotaxisfixedCheckBox.Value == 0
                axis(app.KinPlot,[1E-7 max(table2array(app.timeRG)) min(table2array(app.KinDataPlot)) max(table2array(app.KinDataPlot))]);
            else
                axis(app.KinPlot,[1E-7 max(table2array(app.timeRG)) app.FixedYRange]);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Determine where your m-file's folder is.
            folder = fileparts(which(mfilename));

            % Add that folder plus all subfolders to the path.
            addpath(genpath(folder));

            % Keep most buttons invisble for cleaner startup look
            app.GridLayout3.Visible = false;
            app.GridLayout9.Visible = false;
            app.CenterPanel.Visible = false;
            app.RightPanel.Visible = false;
            app.SaveVarstoWorkspaceButton.Visible = false;
            app.LogLogFigureButton.Visible = false;
            app.ExportFiguresButton.Visible = false;
            app.ResetButton.Visible = false;
            app.ExportLogDataCSVButton.Visible = false;
            app.rst = 0;
            app.iter = 0;
            app.TimeFields = [1E-6; 1E-5; 1E-4; 1E-3; 1E-2; 1E-1];
        end

        % Button pushed function: LoadTDMSdataButton
        function loadDataButton(app, event)
            app.LoadTDMSdataButton.Enable = 'off';
            app.iter = app.iter + 1;
            % Check if we start from startup fcn or reset fcn
            % Set path accordingly
            if app.rst == 0
                try
                    app.LoadCreateError.Text = "Loading data...";
                    FolderPath = uigetdir;
                    FolderContent = dir(FolderPath); %#ok<NASGU>
                    app.LoadTDMSdataButton.Enable = 'on';
                catch
                    app.LoadCreateError.Text = 'Invalid directory, try again';
                    app.LoadTDMSdataButton.Enable = 'on';
                end
            else
                folder = fileparts(which(mfilename));
                addpath(genpath(folder));
                FolderPath = app.Path;
                FolderContent = dir(FolderPath); %#ok<NASGU>
                app.LoadTDMSdataButton.Enable = 'on';
            end

            % Load the appropriate script file to handle loaded TDMS data
            try
                SpectraBuilderloadTDMSfolder;
                %% Check if matlab is installed
                matlab.addons.isAddonEnabled('Image Processing Toolbox')
            catch ERROR
                switch ERROR.identifier
                    case 'MATLAB:UndefinedFunction'
                        app.LoadCreateError.Text = 'Function is not on selected path';
                        return;
                    case 'matlab_addons:enableDisableManagement:invalidIdentifier'
                        app.LoadCreateError.Text = "Please install 'Image Processing Toolbox' to continue";
                        return;
                    otherwise
                        app.LoadCreateError.Text = 'Unknown error';
                        return;
                end
            end

            % Separate property to distinguish proper path
            app.Path = FolderPath;

            % Set all the variables from the loadTDMSfolder to public and
            % private properties for both applications to call on
            app.LinearData = LinData;
            app.LogarithmData = LogData;
            app.TamOn = tamOn;
            app.time = Time;
            app.LogTimeArr = LogTimeArray;
            app.BackgroundLevel = BackgroundLvl;
            app.KinInd = 1;
            app.IsLogDataOnly=false;
            app.changeAxes = true;

            % Reveal all the hidden objects
            app.GridLayout3.Visible = true;
            app.GridLayout9.Visible = true;
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;
            app.SpecPlot.Visible = true; %RG
            app.SpectraTimeWidthLabel.Visible = true;
            app.LinearDataButton.Visible = true;
            app.LogDataButton.Visible=true;
            app.SpectraTimeWidthSlider.Visible = true;
            app.ExportLogDataCSVButton.Visible = true;
            app.ExportSpectraDataButton.Visible = true;
            app.CentertimesforspectraSeperatewithcolonLabel.Visible = true;
            app.KeepSpecPlotYaxisfixedCheckBox.Visible = true;
            app.BackgroundLvlDisplay.Visible = true;
            app.SaveVarstoWorkspaceButton.Visible = true;
            app.LogLogFigureButton.Visible = true;
            app.ExportFiguresButton.Visible = true;
            app.ResetButton.Visible = true;
            app.KeepKinPlotaxisfixedCheckBox.Value = 0;
            app.ButtonGroup.SelectedObject = app.LinearYscaleButton;
            app.LogarithmicdatawillbeexportedtoCSVLabel.Visible = false;
            app.rst = 0;

            % Sort the table columns by wavelength & check if TAS or TAM
            if app.TamOn == 0
                %% Look at the data tables and sort by wavelengths
                app.waves = app.LinearData.Properties.VariableNames(2:end);
                wavelengths_order = cellfun(@(x)sscanf(x, 'x%d'), app.waves);
                [~, sortedWavelengthsOrder] = sort(wavelengths_order);
                app.BackgroundLevel = app.BackgroundLevel(:,sortedWavelengthsOrder);

                %Change sorting array to fit Time in first index
                sortedWavelengthsOrder = sortedWavelengthsOrder + 1;
                sortedWavelengthsOrder = [1 sortedWavelengthsOrder];
                app.LogDataSort = app.LogarithmData(:, sortedWavelengthsOrder);
                app.LinDataSort = app.LinearData(:, sortedWavelengthsOrder);

                % Populate property to use throughout fcns
                % And erase the useless 'x' char in wavelength num
                app.dataRG = app.LogDataSort;
                for i = 1:1:length(app.waves)
                    app.waves(i) = erase(app.waves(i), 'x');
                end

                % Output folder path in text box and continue
                [~, name] = fileparts(app.Path);
                app.LoadCreateError.Text = "Opened folder: " + name;

                RGBuildSpecData(app);
            else
                %% Look at the data tables and sort by position
                app.waves = {Wavelength};

                app.positions = app.LinearData.Properties.VariableNames(2:end);
                positions_order = cellfun(@(x)sscanf(x, 'x%d'), app.positions);
                [~, sortedPositionsOrder] = sort(positions_order);
                app.BackgroundLevel = app.BackgroundLevel(:,sortedPositionsOrder);

                %Change sorting array to fit Time in first index
                sortedPositionsOrder = sortedPositionsOrder + 1;
                sortedPositionsOrder = [1 sortedPositionsOrder];
                app.LogDataSort = app.LogarithmData(:, sortedPositionsOrder);
                app.LinDataSort = app.LinearData(:, sortedPositionsOrder);

                % Populate property to use throughout fcns
                % And erase the useless 'x' char in wavelength num
                app.dataRG = app.LogDataSort;
                for i = 1:1:length(app.positions)
                    app.positions(i) = erase(app.positions(i), 'x');
                end

                % Output folder path in text box and continue
                [~, name] = fileparts(app.Path);
                app.LoadCreateError.Text = "Opened folder: " + name;

                RGBuildSpecData(app);
            end
        end

        % Button pushed function: PrevButton
        function PrevButtonPressed(app, event)
            % Alter index accordingly and replot data
            app.KinInd = app.KinInd - 1;
            if app.KinInd < 1
                app.KinInd = 1;
            end

            % Check if need to plot Log data or Lin data
            if ~app.IsLogDataOnly
                RGPlot(app);
            else
                plotLogData(app);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPressed(app, event)
            % Alter index accordingly and replot data
            app.KinInd = app.KinInd + 1;
            if app.KinInd > app.Traces
                app.KinInd = app.Traces;
            end

            % Check if need to plot Log data or Lin data
            if ~app.IsLogDataOnly
                RGPlot(app);
            else
                plotLogData(app);
            end
        end

        % Value changed function: SpectraTimeWidthSlider
        function TimeWidthChangedFcn(app, event)
            % Update width of the slider to final value
            value = app.SpectraTimeWidthSlider.Value;
            app.SpectraTimeWidthSlider.Value = 5*round(value/5);
            %%FIX THE WAY THIS IS HANDLED. SHOULD JUST UPDATE SHADE AREAS
            %%AROUND CURRENT CENTER
            %             app.XPoints = [];
            %             RGBuildSpecData(app)
        end

        % Value changing function: SpectraTimeWidthSlider
        function TimeWidthChangingFcn(app, event)
            % Keep track of moving values to then send to above fcn
            changingValue = event.Value;
            if changingValue < 5
                changingValue = 5;
            end
            app.SpectraTimeWidthSlider.Value = 5*round(changingValue/5);
        end

        % Value changed function: KeepSpecPlotYaxisfixedCheckBox
        function KeepSpecPlotYaxisfixed(app, event)
            % Keep y-axis fixed for the spec plot
            app.changeAxes = ~ app.changeAxes;
            RGPlot(app)
        end

        % Value changed function: KeepKinPlotaxisfixedCheckBox
        function KeepKinPlotaxisfixed(app, event)
            % Keep y-axis fixed for the kin plot based on log or lin
            if app.KeepKinPlotaxisfixedCheckBox.Value==1
                switch app.KinPlot.YScale
                    case 'log'
                        KinDataArray = table2array(app.KinDataPlot);
                        TimeArray = table2array(app.timeRG);
                        KinDataPlotPos = (KinDataArray(TimeArray>0));
                        KinDataPlotPos = (KinDataPlotPos(KinDataPlotPos>0));
                        app.FixedYRange = [min(KinDataPlotPos) max(KinDataArray)];
                    case 'linear'
                        app.FixedYRange = app.KinPlot.YLim;
                end
            end

            % Check if need to re-plot Log data or Lin data
            if ~app.IsLogDataOnly
                RGPlot(app)
            else
                plotLogData(app)
            end
        end

        % Selection changed function: ButtonGroup
        function selectLogOrLin(app, event)
            % Create var for selected scale and change accordingly
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.LogYScaleButton
                app.KinPlot.YScale = 'log';
            else
                app.KinPlot.YScale = 'linear';
                refline(app.KinPlot, 0, 0);
            end

            % Check if the y-axis is fixed and update fixed y range
            if app.KeepKinPlotaxisfixedCheckBox.Value == 1
                switch app.KinPlot.YScale
                    case 'log'
                        KinDataArray = table2array(app.KinDataPlot);
                        TimeArray = table2array(app.timeRG);
                        KinDataPlotPos = (KinDataArray(TimeArray>0));
                        KinDataPlotPos = (KinDataPlotPos(KinDataPlotPos>0));
                        app.FixedYRange = [min(KinDataPlotPos) max(KinDataArray)];
                    case 'linear'
                        app.FixedYRange = app.KinPlot.YLim;
                end
            end

            % Only call on building spectra if using lin data (nargin=3)
            if ~app.IsLogDataOnly   
%%%%%%%%%%%%%%%%%FIX THIS. SHADED AREA IS SHOWING UP NOW%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%% JUST TURN OFF VISIBILITY FOR ALL OBJECTS IF ALL STORED?
%% CHECKOUT SUPPRESSED MESSAGES THROUGHOUT APP
                RGBuildSpecData(app,1,1);
            end
        end

        % Button pushed function: LinearDataButton
        function setLinData(app, event)
            % Populate the correct property and build specdata again
            app.dataRG = app.LinDataSort;
            RGBuildSpecData(app);
        end

        % Button pushed function: LogDataButton
        function setLogData(app, event)
            % Populate the correct property and build specdata again
            app.dataRG = app.LogDataSort;
            RGBuildSpecData(app);
        end

        % Button pushed function: ExportLogDataCSVButton
        function ExportLogDataCSVButtonPushed(app, event)
            % Reveal export message
            app.LogarithmicdatawillbeexportedtoCSVLabel.Visible = true;

            % Create file name/path to export to
            [~, name] = fileparts(app.Path);
            if app.TamOn == 0
                name = app.Path + "\" + name + "_KIN_TAS.csv";
            else
                name = app.Path + "\" + name + "_KIN_TAM.csv";
            end

            % Write CSV to table and return a message notifying as such
            writetable(app.LogDataSort, name, "Delimiter", ',', "QuoteStrings",true);
            app.LogarithmicdatawillbeexportedtoCSVLabel.Text = 'Data has been exported';
        end

        % Button pushed function: ExportSpectraDataButton
        function ExportSpectraDataButtonPushed(app, event)
            % Use path from data folder to export spectra data
            [~, name] = fileparts(app.Path);
            OutputName = app.Path + "\" + name + "_SPECTRADATA.txt";
            sorted_wavelengths = sort(str2double(app.waves));
            DataToWrite = [sorted_wavelengths' app.SpecDataPlot];

            % Create vars to export data to txt
            Headers = [];
            for i =1:1:size(app.SpecDataPlot,2)
                Headers(1,i+1) = mean(app.ShadeArea{i,1}); %#ok<AGROW>
            end
            OutputData = [Headers; DataToWrite]';
            format_str = ['%.1f' repmat(' %.7f',1,size(app.SpecDataPlot,2)) '\r\n'];
            RGID = fopen(OutputName,'w');
            fprintf(RGID,format_str,OutputData);
            fclose(RGID);
        end

        % Button pushed function: LoadLogData
        function loadLogDataButtonPushed(app, event)
            app.loadLogDataButton.Enable = 'off';
            % Find the csv file and record path and clear plots
            app.LoadCreateError.Text = "Loading data";
            [FileName, FilePath] = uigetfile('*.csv');
            app.LoadCreateError.Text = "log data: " + FileName;

            try
                % Read the data and copy to public property to use
                temp = readtable(fullfile(FilePath, FileName));
            catch ERROR
                switch ERROR.identifier
                    case 'matlab_addons:enableDisableManagement:invalidIdentifier'
                        app.LoadCreateError.Text = "Please install 'Image Processing Toolbox' to continue";
                        app.loadLogDataButton.Enable = 'on';
                        return;
                    otherwise
                        app.LoadCreateError.Text = 'Function is not on selected path';
                        app.loadLogDataButton.Enable = 'on';
                        return;
                end
            end
            app.loadLogDataButton.Enable = 'on';

            % Clear all previous properties that were populated and set
            % index to default value
            clearProperties(app);
            app.KinInd = 1;

            cla(app.KinPlot, "reset");
            cla(app.SpecPlot,"reset");
            app.KeepKinPlotaxisfixedCheckBox.Value = 0;
            app.ButtonGroup.SelectedObject = app.LinearYscaleButton;

            % Check if it is TAS or TAM data and edit names for global use
            if endsWith(FileName, '.csv') == 1
                if endsWith(FileName, 'TAS.csv') == 1
                    app.TamOn = 0;
                elseif endsWith(FileName, 'TAM.csv') == 1
                    app.TamOn = 1;
                else
                    msg = "Wrong input file. Need TAS or TAM data";
                    app.LoadCreateError.Text = msg;
                end
            end

            % Re-apply temp variables to properties to keep useful data
            app.Table = temp;

            if app.TamOn == 0
                % Extract csv header names
                app.waves = app.Table.Properties.VariableNames(2:end);

                % Erase useless 'x' char in wavelength num
                for i = 1:1:length(app.waves)
                    app.waves(i) = erase(app.waves(i), 'x');
                end
            else
                % Extract csv header names
                app.positions = app.Table.Properties.VariableNames(2:end);
                app.waves = extractBefore(app.positions,'nm');

                % Erase useless 'x' char in position num
                for i = 1:1:length(app.positions)
                    app.positions(i) = erase(app.positions(i), 'x');
                end
            end

            % Change look of application to get rid of options that are not
            % available to loaded log data
            app.IsLogDataOnly = true;
            app.Plot_Wavelengths = sort(str2double(app.waves));
            app.GridLayout3.Visible = true;
            app.GridLayout9.Visible = true;
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;
            app.LogLogFigureButton.Visible = true;
            app.SpecPlot.Visible = false;
            app.SpectraTimeWidthLabel.Visible = false;
            app.LinearDataButton.Visible = false;
            app.LogDataButton.Visible=false;
            app.SpectraTimeWidthSlider.Visible = false;
            app.ExportLogDataCSVButton.Visible = false;
            app.ExportSpectraDataButton.Visible = false;
            app.CentertimesforspectraSeperatewithcolonLabel.Visible = false;
            app.KeepSpecPlotYaxisfixedCheckBox.Visible = false;
            app.BackgroundLvlDisplay.Visible = false;
            app.SaveVarstoWorkspaceButton.Visible = false;
            app.ExportFiguresButton.Visible = false;
            app.ResetButton.Visible = false;
            app.LogarithmicdatawillbeexportedtoCSVLabel.Visible = false;

            % After everything is loaded, plot data accordingly
            app.timeRG = app.Table(:,1);
            app.KinData = app.Table(:,2:end);
            plotLogData(app);
        end

        % Callback function
        function TimeFieldsValueChanged(app, event)
            % Update spectra objects once center times are manually changed
            app.TimeFields.Value = event.Value;
            % INSTEAD OF CLEARING PLOT AXES, MAYBE ADD NARGIN = 2 AND
            % UPDATE POSITION AS WELL AS TIME FIELDS WITH SHADED AREA SO
            % EVERYTHING UPDATES DIFFERENTLY WHEN CHANGING TIME VALUE
%             cla(app.KinPlot, 'reset')

%             value = app.TimeFields.Value; %#ok<NASGU>
            RGBuildSpecData(app,1,1);
        end

        % Button pushed function: SaveVarstoWorkspaceButton
        function SaveVarstoWorkspaceButtonPushed(app, event)
            % Call on these properties in order to save to workspace
            app.SpecDataPlot;
            app.dataRG;
            app.BackgroundLevel;

            % Save vars of this function to base workspace
            save_to_base(1);
        end

        % Button pushed function: LogLogFigureButton
        function LogLogFigureButtonPushed(app, event)
            % Disable button while second app is open
            app.LogLogFigureButton.Enable = 'off';

            % Open LogLog App with required arguments
            app.LogApp = LogLogApp(app);
        end

        % Close request function: SpectrabuilderUIFigure
        function SpectrabuilderUIFigureCloseRequest(app, event)
            % Delete both apps when closing the figures
            delete(app.LogApp)
            delete(app)
        end

        % Button pushed function: ExportFiguresButton
        function ExportFiguresButtonPushed(app, event)
            % Create string for figure titles
            string = "@" + app.Plot_Wavelengths(app.KinInd);
            name1 = sprintf('KinFigure%s.pdf',string);
            name2 = sprintf('SpecFigure%s.png',string);

            % Create full path for each name
            place1 = fullfile(app.Path,name1);
            place2 = fullfile(app.Path,name2);

            % Export Vector Image for KinPlot and PNG for SpecPlot
            exportgraphics(app.KinPlot, place1, 'ContentType','vector');
            exportgraphics(app.SpecPlot, place2, 'Resolution', 300);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Create property to know when reset button is pushed
            app.rst = 1;

            % Save path of loaded data so you don't have to call data again
            % Then clear properties and reset to default
            tempPath = app.Path;
            clearProperties(app);
            app.Path = tempPath;


            loadDataButton(app);
        end

        % Size changed function: SpectrabuilderUIFigure
        function updateAppLayout(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectrabuilderUIFigure = uifigure('Visible', 'off');
            app.SpectrabuilderUIFigure.Name = 'Spectrabuilder';
            app.SpectrabuilderUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpectrabuilderUIFigureCloseRequest, true);
            app.SpectrabuilderUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SpectrabuilderUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadTDMSdataButton = uibutton(app.GridLayout2, 'push');
            app.LoadTDMSdataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButton, true);
            app.LoadTDMSdataButton.Text = 'Load TDMS data';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.LinearDataButton = uibutton(app.GridLayout9, 'push');
            app.LinearDataButton.ButtonPushedFcn = createCallbackFcn(app, @setLinData, true);
            app.LinearDataButton.Text = 'Linear Data';
            app.LogDataButton = uibutton(app.GridLayout9, 'push');
            app.LogDataButton.ButtonPushedFcn = createCallbackFcn(app, @setLogData, true);
            app.LogDataButton.Text = 'Log Data';
            app.LoadCreateError = uilabel(app.GridLayout2);
            app.LoadCreateError.Text = '';
            app.LoadLogData = uibutton(app.GridLayout2, 'push');
            app.LoadLogData.ButtonPushedFcn = createCallbackFcn(app, @loadLogDataButtonPushed, true);
            app.LoadLogData.Text = 'Load Log Data';
            app.SaveVarstoWorkspaceButton = uibutton(app.GridLayout2, 'push');
            app.SaveVarstoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVarstoWorkspaceButtonPushed, true);
            app.SaveVarstoWorkspaceButton.Text = 'Save Vars to Workspace';
            app.LogLogFigureButton = uibutton(app.GridLayout2, 'push');
            app.LogLogFigureButton.ButtonPushedFcn = createCallbackFcn(app, @LogLogFigureButtonPushed, true);
            app.LogLogFigureButton.Text = 'LogLog Figure';
            app.ExportFiguresButton = uibutton(app.GridLayout2, 'push');
            app.ExportFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFiguresButtonPushed, true);
            app.ExportFiguresButton.Text = 'Export Figures';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExportLogDataCSVButton = uibutton(app.GridLayout2, 'push');
            app.ExportLogDataCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLogDataCSVButtonPushed, true);
            app.ExportLogDataCSVButton.Text = 'Export Log Data CSV';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.SpecPlot = uiaxes(app.GridLayout3);
            title(app.SpecPlot, 'Spectra')
            xlabel(app.SpecPlot, 'Wavelength (nm)')
            ylabel(app.SpecPlot, 'ΔOD or %Abs')
            app.KinPlot = uiaxes(app.GridLayout3);
            title(app.KinPlot, 'Kinetics')
            xlabel(app.KinPlot, 'Time (s)')
            ylabel(app.KinPlot, 'ΔOD or %Abs')
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.KeepKinPlotaxisfixedCheckBox = uicheckbox(app.GridLayout5);
            app.KeepKinPlotaxisfixedCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeepKinPlotaxisfixed, true);
            app.KeepKinPlotaxisfixedCheckBox.Text = 'Keep Y axis fixed';
            app.ButtonGroup = uibuttongroup(app.GridLayout5);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectLogOrLin, true);
            app.LogYScaleButton = uiradiobutton(app.ButtonGroup);
            app.LogYScaleButton.Text = 'Log Y Scale';
            app.LinearYscaleButton = uiradiobutton(app.ButtonGroup);
            app.LinearYscaleButton.Text = 'Linear Y scale';
            app.LinearYscaleButton.Value = true;
            app.KeepSpecPlotYaxisfixedCheckBox = uicheckbox(app.GridLayout3);
            app.KeepSpecPlotYaxisfixedCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeepSpecPlotYaxisfixed, true);
            app.KeepSpecPlotYaxisfixedCheckBox.Text = 'Keep Y axis fixed';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.PrevButton = uibutton(app.GridLayout6, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPressed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.GridLayout6, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPressed, true);
            app.NextButton.Text = 'Next';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.SpectraTimeWidthLabel = uilabel(app.GridLayout4);
            app.SpectraTimeWidthLabel.Text = 'Spectra Time Width(%)';
            app.CentertimesforspectraSeperatewithcolonLabel = uilabel(app.GridLayout4);
            app.CentertimesforspectraSeperatewithcolonLabel.Text = {'Center times for spectra'; '(Seperate with colon)'};
            app.ExportSpectraDataButton = uibutton(app.GridLayout4, 'push');
            app.ExportSpectraDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSpectraDataButtonPushed, true);
            app.ExportSpectraDataButton.Text = 'Export SpectraData';
            app.LogarithmicdatawillbeexportedtoCSVLabel = uilabel(app.GridLayout4);
            app.LogarithmicdatawillbeexportedtoCSVLabel.Visible = 'off';
            app.LogarithmicdatawillbeexportedtoCSVLabel.Text = 'Logarithmic data will be exported to CSV';
            app.BackgroundLvlDisplay = uilabel(app.GridLayout4);
            app.BackgroundLvlDisplay.Text = 'Background level was _ V';
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'1'; '2'};
            app.UITable.RowName = {};
            app.KineticLevelDisplay = uilabel(app.GridLayout4);
            app.KineticLevelDisplay.Text = 'Displaying kinetic at _ nm';
            app.SpectraTimeWidthSlider = uislider(app.SpectrabuilderUIFigure);
            app.SpectraTimeWidthSlider.ValueChangedFcn = createCallbackFcn(app, @TimeWidthChangedFcn, true);
            app.SpectraTimeWidthSlider.ValueChangingFcn = createCallbackFcn(app, @TimeWidthChangingFcn, true);
            app.SpectraTimeWidthSlider.MinorTicks = [0 5 10 15 25 30 35 45 50 55 65 70 75 85 90 95];
            app.SpectraTimeWidthSlider.Value = 10;
            % Show the figure after all components are created
            app.SpectrabuilderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SolarSpecGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectrabuilderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectrabuilderUIFigure)
        end
    end
end